classdef AcousticCanvas < matlab.apps.AppBase

    % Properties that correspond to app components
    properties (Access = public)
        UIFigure                        matlab.ui.Figure
        RunBeamformerButton             matlab.ui.control.Button
        ShowAmbientButton               matlab.ui.control.Button
        xmEditFieldLabel                matlab.ui.control.Label
        xmEditField                     matlab.ui.control.EditField
        ArrayLabel                      matlab.ui.control.Label
        ymEditFieldLabel                matlab.ui.control.Label
        ymEditField                     matlab.ui.control.EditField
        zmEditFieldLabel                matlab.ui.control.Label
        zmEditField                     matlab.ui.control.EditField
        TabGroup                        matlab.ui.container.TabGroup
        ArrayTab                        matlab.ui.container.Tab
        UIArray                         matlab.ui.control.UIAxes
        AmbientTab                      matlab.ui.container.Tab
        UIScenario                      matlab.ui.control.UIAxes
        AcousticImageTab                matlab.ui.container.Tab
        UIAcousticImage                 matlab.ui.control.UIAxes
        DynamicrangedBEditField         matlab.ui.control.NumericEditField
        ShowSourcesCheckBox             matlab.ui.control.CheckBox
        ShowArrayCheckBox               matlab.ui.control.CheckBox
        DynamicrangedBSliderLabel       matlab.ui.control.Label
        DynamicrangedBSlider            matlab.ui.control.Slider
        ShowLocalMaximaCheckBox         matlab.ui.control.CheckBox
        ExportButton                    matlab.ui.control.Button
        PathEditFieldLabel              matlab.ui.control.Label
        PathEditField                   matlab.ui.control.EditField
        TypeDropDown_2Label             matlab.ui.control.Label
        TypeExportDropDown              matlab.ui.control.DropDown
        DPIEditFieldLabel               matlab.ui.control.Label
        DPIEditField                    matlab.ui.control.NumericEditField
        ScanningPlaneLabel              matlab.ui.control.Label
        AcousticCanvasSimulatorLabel    matlab.ui.control.Label
        DistancetoscanningplanemEditFieldLabel  matlab.ui.control.Label
        DistancetoscanningplanemEditField  matlab.ui.control.NumericEditField
        ScanningplanewidthmEditFieldLabel  matlab.ui.control.Label
        ScanningplanewidthmEditField    matlab.ui.control.NumericEditField
        ScanningplaneheightmEditFieldLabel  matlab.ui.control.Label
        ScanningplaneheightmEditField   matlab.ui.control.NumericEditField
        NumberofScanningpointswidthEditFieldLabel  matlab.ui.control.Label
        NumberofScanningpointswidthEditField  matlab.ui.control.NumericEditField
        NumberofScanningpointsheightEditFieldLabel  matlab.ui.control.Label
        NumberofScanningpointsheightEditField  matlab.ui.control.NumericEditField
        RoomConfigLabel                 matlab.ui.control.Label
        ReflectionCoefficientEditFieldLabel  matlab.ui.control.Label
        ReflectionCoefficientEditField  matlab.ui.control.NumericEditField
        RoomDimensionsmEditFieldLabel   matlab.ui.control.Label
        RoomDimensionsmEditField        matlab.ui.control.EditField
        EstimatedRT60Label              matlab.ui.control.Label
        RT60ValueLabel                  matlab.ui.control.Label
        BeamformerLabel                 matlab.ui.control.Label
        FrequencyBandDropDownLabel      matlab.ui.control.Label
        FrequencyBandDropDown           matlab.ui.control.DropDown
        CenterFrequencyEditFieldLabel   matlab.ui.control.Label
        CenterFrequencyEditField        matlab.ui.control.NumericEditField
        ExamplesDropDownLabel           matlab.ui.control.Label
        ExamplesDropDown                matlab.ui.control.DropDown
        EstimateRT60Button              matlab.ui.control.Button
        BeamformerLabel_2               matlab.ui.control.Label
        BeamformerDropDown              matlab.ui.control.DropDown
        FrequencyBinEditFieldLabel      matlab.ui.control.Label
        FrequencyBinEditField           matlab.ui.control.NumericEditField
        LoopGainEditFieldLabel          matlab.ui.control.Label
        LoopGainEditField               matlab.ui.control.NumericEditField
        MaxIterationsEditFieldLabel     matlab.ui.control.Label
        MaxIterationsEditField          matlab.ui.control.NumericEditField
        GenSignalsButton                matlab.ui.control.Button
        TabGroup2                       matlab.ui.container.TabGroup
        Source1Tab                      matlab.ui.container.Tab
        SourceLocationLabel             matlab.ui.control.Label
        SignalfrequencyHzEditFieldLabel  matlab.ui.control.Label
        SignalfrequencyHzEditField      matlab.ui.control.NumericEditField
        xmEditField_2Label              matlab.ui.control.Label
        xSourceEditField                matlab.ui.control.EditField
        ymEditField_2Label              matlab.ui.control.Label
        ySourceEditField                matlab.ui.control.EditField
        zmEditField_2Label              matlab.ui.control.Label
        zSourceEditField                matlab.ui.control.EditField
        TypeDropDownLabel               matlab.ui.control.Label
        TypeDropDown                    matlab.ui.control.DropDown
        WAVFilenameEditFieldLabel       matlab.ui.control.Label
        WAVFilenameEditField            matlab.ui.control.EditField
        GenRunButton                    matlab.ui.control.Button
        AuxiliarcodeLabel               matlab.ui.control.Label
        AuxiliarcodeTextArea            matlab.ui.control.TextArea
        ShowArrayButton                 matlab.ui.control.Button
        AddSourceButton                 matlab.ui.control.Button
        RemoveSourceButton              matlab.ui.control.Button
        SourcesLabel                    matlab.ui.control.Label
        ContextMenu                     matlab.ui.container.ContextMenu
        DMenu                           matlab.ui.container.Menu
        DMenu_2                         matlab.ui.container.Menu
        InputSignalLabel                matlab.ui.control.Label
        FrequencysampleHzEditFieldLabel  matlab.ui.control.Label
        FrequencysampleHzEditField      matlab.ui.control.NumericEditField
        DurationsEditFieldLabel         matlab.ui.control.Label
        DurationsEditField              matlab.ui.control.NumericEditField
        SNRdBEditFieldLabel             matlab.ui.control.Label
        SNRdBEditField                  matlab.ui.control.NumericEditField
        WindowLengthmsEditFieldLabel    matlab.ui.control.Label
        WindowLengthmsEditField         matlab.ui.control.NumericEditField
    end

    
    properties (Access = private)
        fs  % [Hz]
        f   % [Hz]
        c
        f_ob
        type
        
        nameAudio
        duration
        
        SNR
        room 
        refCoef 
        RT60 
        
        nSources
        xPos
        yPos
        zPos
        wm
        xPosSource
        yPosSource
        zPosSource
        scanningPointsX
        scanningPointsY
        scanningPointsZ
        scanningPointsH
        scanningPointsL
         
        distanceToScanningPlane
        maxScanningPlaneExtentL
        maxScanningPlaneExtentH
        numberOfScanningPointsL
        numberOfScanningPointsH
        normalScanningPlane
        
        thetaScanAngles
        phiScanAngles
        
        inputSignal
        S
        beamformer
        loopGain
        maxIterations
    end
    
    methods (Access = private)
        
        function plotSteeredResponseXY_app(app, S, scanningPointsX, scanningPointsY, interpolationFactor, dynamicRange)
        
            if ~exist('interpolationFactor', 'var')
                interpolationFactor = 2;
            end
            if ~exist('dynamicRange', 'var')
                dynamicRange = 6;
            end
            
            maxDynamicRange = 30;
            display = '2D';
            
    %         fig = figure;
    %         ax = axes('Parent', fig);
    %         fig.Name = 'Steered response';
            ax = app.UIAcousticImage;
            
            
            %Interpolate for higher resolution
            interpolationMethod = 'spline';
            
            S = interp2(S, interpolationFactor, interpolationMethod);
            scanningPointsX = interp2(scanningPointsX, interpolationFactor, interpolationMethod);
            scanningPointsY = interp2(scanningPointsY, interpolationFactor, interpolationMethod);
            
            S = abs(S)/max(max(abs(S)));
            S = 10*log10(S);
            
            
            steeredResponsePlot = surf(ax, scanningPointsX, scanningPointsY, S,...
                'EdgeColor','none',...
                'PickAbleParts', 'none');
%                 'FaceAlpha',0.5, ...
            
            %Axes
            xlabel(ax, 'x [m]')
            ylabel(ax, 'y [m]')
            zlabel(ax, 'dB');
            
            cb=colorbar(ax);
            cb.Label.String = 'dB';
            cb.Label.FontSize = 12;
            
            ylim(ax, [min(scanningPointsY(:)) max(scanningPointsY(:))])
            xlim(ax, [min(scanningPointsX(:)) max(scanningPointsX(:))])
            zlim(ax, [0 maxDynamicRange])
            
            ax.Color = [1 1 1];
            ax.XColor = [1 1 1]*0;
            ax.YColor = [1 1 1]*0;
            ax.ZColor = [1 1 1]*0;
            
            if maxDynamicRange <= 30
                dynamicRangeTickStep = 5;
            else
                dynamicRangeTickStep = 10;
            end
            ax.ZTick = 0:dynamicRangeTickStep:maxDynamicRange;
            
            daspect(ax, [1 1 maxDynamicRange/max(max(scanningPointsX(:)))])
            box(ax, 'off')
            grid(ax, 'on')

            
            %Add dynamic range slider
%             range = [0.01 maxDynamicRange];
%             dynamicRangeSlider = uicontrol(ax,'style', 'slider', ...
%                 'Units', 'normalized',...
%                 'position', [0.92 0.18 0.03 0.6],...
%                 'value', log10(dynamicRange),...
%                 'min', log10(range(1)),...
%                 'max', log10(range(2)));
%             addlistener(dynamicRangeSlider,'ContinuousValueChange',@(obj, evt) changeDynamicRange(obj, evt, 10^obj.Value, steeredResponsePlot));


            changeView(ax, ax, display);
            changeDynamicRange(app,ax, dynamicRange, steeredResponsePlot);
   
        
           %Context menu to change 2D/3D view
%             cmFigure = uicontextmenu;
%             uimenu('Parent', cmFigure, 'Label', '2D', 'Callback',{ @changeView, '2D' });
%             uimenu('Parent', cmFigure, 'Label', '3D', 'Callback',{ @changeView, '3D' });
%             ax.UIContextMenu = cmFigure;
%             steeredResponsePlot.UIContextMenu = cmFigure;
        
            function changeView(~, ~, selectedView)
                display = selectedView;
                if strcmp(display,'2D')
                    view(ax, [0 90])
                else
                    %ax.CameraPosition = [20, 12, 700];
                    %ax.CameraUpVector = [0 1 0];
                    view(ax, [30 30])
                end
            end
        
        end
        
         function changeDynamicRange(~, ax, selectedDynamicRange, ~)
                %         dynamicRange = selectedDynamicRange;
        %         steeredResponsePlot.ZData = S+dynamicRange;
        %         
        %         caxis(ax, [0 dynamicRange]);
        %         zlim(ax, [0 maxDynamicRange])
        %         title(ax, ['Dynamic range: ' sprintf('%0.2f', dynamicRange) ' dB'], 'fontweight', 'normal','Color',[0 0 0]);
                dynamicRange = selectedDynamicRange;
        %         steeredResponsePlot.ZData = S;
                
                caxis(ax, [(-dynamicRange) 0]);
                zlim(ax, [(-dynamicRange) inf])
                title(ax, ['Dynamic range: ' sprintf('%0.2f', dynamicRange) ' dB'], 'fontweight', 'normal','Color',[0 0 0]);
         end
         
         
        function read_inputs(app)
            app.fs = app.FrequencysampleHzEditField.Value; % [Hz]
            app.f = app.SignalfrequencyHzEditField.Value;%str2double(app.SignalfrequencyHzEditField.Value); % [Hz]
            
            app.room = eval(app.RoomDimensionsmEditField.Value);
            app.refCoef = app.ReflectionCoefficientEditField.Value;
                       
            if strcmp(app.FrequencyBandDropDown.Value,'All')
                app.f_ob=0;
            else
                app.f_ob = app.CenterFrequencyEditField.Value; % [Hz]
            end
            
            app.nameAudio = app.WAVFilenameEditField.Value;
            app.duration = app.DurationsEditField.Value;
            app.SNR = app.SNRdBEditField.Value;
            
%             for i=1:length(app.AuxiliarcodeTextArea.Value)
%                 eval(app.AuxiliarcodeTextArea.Value{i});
%             end
            aux=[];
            for i=1:length(app.AuxiliarcodeTextArea.Value)
                aux=[aux, app.AuxiliarcodeTextArea.Value{i}];
            end
            eval(aux);       
            app.zPos = eval(app.zmEditField.Value);
            app.xPos = eval(app.xmEditField.Value);
            app.yPos = eval(app.ymEditField.Value);
%             aConverted=str2double(strsplit(value,' '))
            app.wm = ones(1, numel(app.xPos))/numel(app.xPos);
            
%             if app.nSources == 1
%                 app.zPosSource = eval(app.zSourceEditField.Value);
%                 app.xPosSource = eval(app.xSourceEditField.Value);
%                 app.yPosSource = eval(app.ySourceEditField.Value);
%             else
%                 app.zPosSource = [eval(app.zSourceEditField.Value) eval(app.zSourceEditField_2.Value)];
%                 app.xPosSource = [eval(app.xSourceEditField.Value) eval(app.xSourceEditField_2.Value)];
%                 app.yPosSource = [eval(app.ySourceEditField.Value) eval(app.ySourceEditField_2.Value)];
%             
%             end
            app.zPosSource=[];
            app.yPosSource=[];
            app.xPosSource=[];
            for i=1:app.nSources
                aux = app.TabGroup2.Children(i).Children.findobj('Type','uieditfield');
                app.zPosSource = [app.zPosSource eval(aux(end-2).Value)];
                app.yPosSource = [app.yPosSource eval(aux(end-1).Value)];
                app.xPosSource = [app.xPosSource eval(aux(end).Value)];
            end
             
                
            % Define scanning grid
            app.distanceToScanningPlane = app.DistancetoscanningplanemEditField.Value;%4; %[m]
            app.maxScanningPlaneExtentL = app.ScanningplanewidthmEditField.Value/2 ;%5; %[m];
            app.maxScanningPlaneExtentH = app.ScanningplaneheightmEditField.Value/2;%5; %[m];
            app.numberOfScanningPointsL = app.NumberofScanningpointswidthEditField.Value;%100;
            app.numberOfScanningPointsH = app.NumberofScanningpointsheightEditField.Value;%100;
%             app.normalScanningPlane = app.ScanningplanenormalDropDown.Value;
                    
            app.beamformer=app.BeamformerDropDown.Value;
            app.loopGain = app.LoopGainEditField.Value;
            app.maxIterations = app.MaxIterationsEditField.Value;
            app.c = 343; % [m/s]
        end
        
        function calculate_sp(app)
            %% Calculate Scanning Plane
            [app.thetaScanAngles,app.phiScanAngles,...
            app.scanningPointsX,app.scanningPointsY,app.scanningPointsZ] = createScanningPlane(...
            app.maxScanningPlaneExtentL,app.maxScanningPlaneExtentH,app.numberOfScanningPointsL,...
            app.numberOfScanningPointsH,app.distanceToScanningPlane,app.xPos,app.yPos,app.zPos);
        end
        
    end


    % Callbacks that handle component events
    methods (Access = private)

        % Code that executes after component creation
        function startupFcn(app)
            app.nSources = 1;
            
            addpath('bib\');
            TypeDropDownValueChanged(app);
%             if isempty(gcp('nocreate'))
%                 parpool;
%             end
            EstimateRT60ButtonPushed(app);
            BeamformerDropDownValueChanged(app);
            TypeExportDropDownValueChanged(app);
            
            
%             app.UIFigure.GraphicsSmoothing = 'off';
%             opengl software;
        end

        % Button pushed function: RunBeamformerButton
        function RunBeamformerButtonPushed(app, event)
            app.TabGroup.SelectedTab = app.AcousticImageTab;
            read_inputs(app);
            d = uiprogressdlg(app.UIFigure,'Title','Please Wait',...
                    'Message','Generating Scan Plane');
                
            calculate_sp(app);
            
            % Generate Input Signals
            d.Value = .2; 
            if strcmp(app.beamformer,'CBF')
                d.Message = 'Running Conventional Beamformer';

%                 NFFT=2^nextpow2(app.fs*0.032); %32ms     
                NFFT=2^nextpow2(app.fs*app.WindowLengthmsEditField.Value/1000);
                if app.f_ob~=0
                    f_min = round(app.f_ob/sqrt(2));
                    f_max = round(app.f_ob*sqrt(2));
                else
                    f_min = 20;
                    f_max = app.fs/2;
                end
                app.S = steeredResponseCBF(app.inputSignal, app.fs, app.wm, app.xPos, app.yPos, app.zPos, app.f, app.c, app.thetaScanAngles, app.phiScanAngles, NFFT, f_min, f_max, app.scanningPointsX, app.scanningPointsY, app.scanningPointsZ);
            else
                %Calculate cross spectral matrix. Since we are beamforming in the frequency
                %domain, we need a specific R matrix for each scanning frequency.
%                 nFFT = 2^nextpow2(app.fs*0.032); %32ms  ;
                nFFT = 2^nextpow2(app.fs*app.WindowLengthmsEditField.Value/1000);
                
                K = floor(length(app.inputSignal)/nFFT);
%                 R = CSM(app.inputSignal, app.f, app.fs, nFFT, K);
                R = CSM(app.inputSignal, app.FrequencyBinEditField.Value, app.fs, nFFT, K);
            
                %Calculate steering vector
                e = steeringVector(app.xPos, app.yPos, app.zPos, app.f, app.c, app.thetaScanAngles, app.phiScanAngles);
                
                switch app.beamformer
                    case 'DAS'
                        d.Message = 'Running  Delay-And-Sum (single frequency) Beamformer';
                        %Calculate delay-and-sum steered power
                        app.S = steeredResponseDelayAndSum(R, e, app.wm);            
                    case 'MV'
                        d.Message = 'Running Minimum Variance Beamformer';
                        %Or minimum variance
                        app.S = steeredResponseMinimumVariance(R, e);
                    case 'FB'
                        d.Message = 'Running Functional Beamformer';
                        %Or functional beamforming
                        app.S = steeredResponseFunctionalBeamforming(R, e);
                    case 'CLEAN'
                        d.Message = 'Running  CLEAN Beamformer';
                        %Or CLEAN deconvolution
                        [app.S, msg] = deconvolutionClean(R, e, app.wm, app.loopGain, app.maxIterations);
                        msg_title = 'CLEAN Beamformer';
                    case 'CLEANSC'
                        d.Message = 'Running  CLEAN-SC Beamformer';
                        %Or CLEAN SC deconvolution
                        [app.S, msg] = deconvolutionCleanSC(R, e, app.wm, app.loopGain, app.maxIterations);
                        msg_title = 'CLEAN-SC Beamformer';
                end
%                 app.S = steeredResponseDelayAndSumGrythe(inputSignal, elementWeights, app.xPos, app.yPos, app.zPos, app.f, app.c, app.thetaScanAngles, app.phiScanAngles);
            end
            d.Value = .9; d.Message = 'Plotting Acoustic Image';
            
            
            %Plot the steered response in cartesian coordinate system rather than UV
            interpolationFactor = 0; %interpolate for higher resolution, 0 equals original
            
            plotSteeredResponseXY_app(app, app.S, app.scanningPointsX, app.scanningPointsY, interpolationFactor, app.DynamicrangedBEditField.Value);
            
%             if app.ShowSourcesCheckBox.Value
                hold(app.UIAcousticImage,'on')
                plot3(app.UIAcousticImage,app.xPosSource,app.yPosSource,zeros(size(app.xPosSource))+.001,'r^','MarkerFaceColor','r','MarkerSize',10)
%                 hold(app.UIAcousticImage,'off')
%             end
%             if app.ShowArrayCheckBox.Value
%                 hold(app.UIAcousticImage,'on')
                plot3(app.UIAcousticImage,app.xPos,app.yPos,zeros(size(app.yPos)),'ko','MarkerFaceColor','k')
                view(app.UIAcousticImage,2)
                
%         end
        
        S = abs(app.S)/max(max(abs(app.S)));S = 10*log10(S);
        aux=imregionalmax(S);
        plot3(app.UIAcousticImage,app.scanningPointsX(aux), app.scanningPointsY(aux),S(aux),'ko','MarkerFaceColor','g','MarkerSize',8)
        hold(app.UIAcousticImage,'off')
        
        app.UIAcousticImage.Children(1).Visible = app.ShowLocalMaximaCheckBox.Value;
        app.UIAcousticImage.Children(2).Visible = app.ShowArrayCheckBox.Value;
        app.UIAcousticImage.Children(3).Visible = app.ShowSourcesCheckBox.Value;
        
        allChildren = get(app.UIAcousticImage, 'Children');
        legend(app.UIAcousticImage,allChildren(1:end-1),'Local Maxima','Microphone','Source','Location','northeastoutside');
        
%             if save_image
%                 title([num2str(app.f) ' Hz'])
%                 exportgraphics(app.UIAcousticImage,['imagens/' num2str(dynamicRange) 'dB_' num2str(f) 'Hz.png'],'Resolution',600);
%             end
            
            
            d.Value = 1; d.Message = 'Finished';
            pause(.1);
            close(d);
            if exist('msg','var')
                uialert(app.UIFigure,msg,msg_title,'Icon','info');
            end
            clear msg;
        end

        % Button pushed function: ShowAmbientButton
        function ShowAmbientButtonPushed(app, event)
            app.TabGroup.SelectedTab = app.AmbientTab;
            
            read_inputs(app);
            calculate_sp(app);
            
            app.UIScenario.Color = [1 1 1];
            plot3(app.UIScenario,app.xPos,app.yPos,app.zPos,'bo','MarkerFaceColor','b','MarkerSize',2)
            hold(app.UIScenario, 'on')
            plot3(app.UIScenario,app.xPosSource,app.yPosSource,app.zPosSource,'r^','MarkerFaceColor','r')
%             plot3(app.UIScenario,app.scanningPointsX,app.scanningPointsY,app.scanningPointsZ,'ks','MarkerFaceColor','k','MarkerSize',10)
            patch(app.UIScenario,...
                [app.scanningPointsX(1) app.scanningPointsX(1) app.scanningPointsX(end) app.scanningPointsX(end)],...
                [app.scanningPointsY(1) app.scanningPointsY(end) app.scanningPointsY(end) app.scanningPointsY(1)],...
                [app.scanningPointsZ(1) app.scanningPointsZ(end) app.scanningPointsZ(1) app.scanningPointsZ(end)],...
                'g','FaceAlpha',.3,'EdgeColor','none');
            % plotcube(app.room,[-app.room(1:2)/2 0],.1,[.5 .5 .5])
%             [edges,origin,alpha,clr] = deal(inArgs{:});
            if app.refCoef~=0
                    XYZ = { ...
                      [0 0 0 0]  [0 0 1 1]  [0 1 1 0] ; [1 1 1 1]  [0 0 1 1]  [0 1 1 0] ; ...
                      [0 1 1 0]  [0 0 0 0]  [0 0 1 1] ; [0 1 1 0]  [1 1 1 1]  [0 0 1 1] ; ...
                      [0 1 1 0]  [0 0 1 1]  [0 0 0 0] ; [0 1 1 0]  [0 0 1 1]  [1 1 1 1] };
                    
                    XYZ = mat2cell(...
                      cellfun( @(x,y,z) x*y+z , ...
                        XYZ , ...
                        repmat(mat2cell(app.room,1,[1 1 1]),6,1) , ...
                        repmat(mat2cell([-app.room(1:2)/2 0],1,[1 1 1]),6,1) , ...
                        'UniformOutput',false), ...
                      6,[1 1 1]);
                    
                    
                    cellfun(@patch,repmat({app.UIScenario},6,1),...
                      XYZ{1},XYZ{2},XYZ{3},...
                      repmat({[.5 .5 .5]},6,1),...
                      repmat({'FaceAlpha'},6,1),...
                      repmat({.1},6,1)...
                      );
            end
            xlabel(app.UIScenario,'x (m)');
            ylabel(app.UIScenario,'y (m)');
            zlabel(app.UIScenario,'z (m)')
            legend(app.UIScenario,'Microphones','Sources','Scanning Plane','Location','northwest')
            % axis([0 inf 0 inf 0 inf])
            view(app.UIScenario,3)
            axis(app.UIScenario,'equal')
            hold(app.UIScenario, 'off')
        end

        % Value changed function: TypeDropDown
        function TypeDropDownValueChanged(app, event)
            value = app.TypeDropDown.Value;
            
            for i=1:app.nSources 
                tab = app.TabGroup2.Children(i).Children;
                switch tab.findobj('type','uidropdown').Value
                    case 'audio'
                        tab.findobj('Type','uinumericeditfield').Visible = 'off';
                        tab.findobj('Type','uinumericeditfield').Editable = 'off';
                        tab.findobj('Text','Signal frequency (Hz)').Visible = 'off';
    %                     
                        tab(1).Visible = 'on';
                        tab(1).Editable = 'on';
                        tab.findobj('Text','WAV Filename').Visible = 'on';
                    case 'senoide'
                        tab.findobj('Type','uinumericeditfield').Visible = 'on';
                        tab.findobj('Type','uinumericeditfield').Editable = 'on';
                        tab.findobj('Text','Signal frequency (Hz)').Visible = 'on';
                        tab(1).Visible = 'off';
                        tab(1).Editable = 'off';
                        tab.findobj('Text','WAV Filename').Visible = 'off';
    %                     app.SignalfrequencyHzEditField.Visible = 'on';
    %                     app.SignalfrequencyHzEditField.Editable = 'on';
    %                     app.SignalfrequencyHzEditFieldLabel.Visible = 'on';
    %                     
    %                     app.WAVFilenameEditField.Visible = 'off';
    %                     app.WAVFilenameEditField.Editable = 'off';
    %                     app.WAVFilenameEditFieldLabel.Visible  = 'off';
                    case 'wgn' 
                        tab.findobj('Type','uinumericeditfield').Visible = 'off';
                        tab.findobj('Type','uinumericeditfield').Editable = 'off';
                        tab.findobj('Text','Signal frequency (Hz)').Visible = 'off';
                        tab(1).Visible = 'off';
                        tab(1).Editable = 'off';
                        tab.findobj('Text','WAV Filename').Visible = 'off';
    %                     app.SignalfrequencyHzEditField.Visible = 'off';
    %                     app.SignalfrequencyHzEditField.Editable = 'off';
    %                     app.SignalfrequencyHzEditFieldLabel.Visible = 'off';
    %                     
    %                     app.WAVFilenameEditField.Visible = 'off';
    %                     app.WAVFilenameEditField.Editable = 'off';
    %                     app.WAVFilenameEditFieldLabel.Visible = 'off';
                end
            end
            
%             for i=1:app.nSources
%                     tab = app.TabGroup2.Children(i).Children;
%                     aux = tab.findobj('type','uidropdown').Value;
%                     switch app.TypeDropDown_2.Value
%                         case 'audio'
%                             par = {par{:},['./bib/' tab(1).Value]};
%                         case 'wgn'
%                             par = {par{:},[fs, Nsig/fs]};
%                         case 'senoide'
%                             par = {par{:},[fs, Nsig/fs, tab.findobj('type','uinumericeditfield').Value]};
%                     end
%                     type = {type{:}, aux};
%             end
        end

        % Value changed function: FrequencyBandDropDown
        function FrequencyBandDropDownValueChanged(app, event)
            value = app.FrequencyBandDropDown.Value;
            if strcmp(value,'All')
                app.CenterFrequencyEditField.Visible = 'off';
                app.CenterFrequencyEditField.Editable = 'off';
                app.CenterFrequencyEditFieldLabel.Visible = 'off';
            else 
                app.CenterFrequencyEditField.Visible = 'on';
                app.CenterFrequencyEditField.Editable = 'on';
                app.CenterFrequencyEditFieldLabel.Visible = 'on';
            end
        end

        % Callback function
        function UpdateImageButtonPushed(app, event)
            %Plot the steered response in cartesian coordinate system rather than UV
            interpolationFactor = 2; %interpolate for higher resolution, 0 equals original
            
            plotSteeredResponseXY_app(app, app.S, app.scanningPointsX, app.scanningPointsY, interpolationFactor, app.DynamicrangedBEditField.Value);
            
%             if app.ShowSourcesCheckBox.Value
                hold(app.UIAcousticImage,'on')
                plot3(app.UIAcousticImage,app.xPosSource,app.yPosSource,30*ones(size(app.xPosSource)),'r^','MarkerFaceColor','r','MarkerSize',10)
%                 hold(app.UIAcousticImage,'off')
%             end
%             if app.ShowArrayCheckBox.Value
%                 hold(app.UIAcousticImage,'on')
                plot3(app.UIAcousticImage,app.xPos,app.yPos,30*ones(size(app.yPos)),'ko','MarkerFaceColor','k')
                view(app.UIAcousticImage,2)
                hold(app.UIAcousticImage,'off')
%         end
        
        app.UIAcousticImage.Children(1).Visible = app.ShowArrayCheckBox.Value;
        app.UIAcousticImage.Children(2).Visible = app.ShowSourcesCheckBox.Value;
        
%             if save_image
%                 title([num2str(app.f) ' Hz'])
%                 exportgraphics(app.UIAcousticImage,['imagens/' num2str(dynamicRange) 'dB_' num2str(f) 'Hz.png'],'Resolution',600);
%             end
        end

        % Value changed function: ShowLocalMaximaCheckBox
        function ShowLocalMaximaCheckBoxValueChanged(app, event)
            value = app.ShowLocalMaximaCheckBox.Value;
            if ~isempty(app.UIAcousticImage.Children)
                app.UIAcousticImage.Children(1).Visible = value;
            end
        end

        % Value changed function: ShowArrayCheckBox
        function ShowArrayCheckBoxValueChanged(app, event)
            value = app.ShowArrayCheckBox.Value;
            if ~isempty(app.UIAcousticImage.Children)
                app.UIAcousticImage.Children(2).Visible = value;
            end
        end

        % Value changed function: ShowSourcesCheckBox
        function ShowSourcesCheckBoxValueChanged(app, event)
            value = app.ShowSourcesCheckBox.Value;
            if ~isempty(app.UIAcousticImage.Children)
                app.UIAcousticImage.Children(3).Visible = value;
            end
        end

        % Value changed function: ExamplesDropDown
        function ExamplesDropDownValueChanged(app, event)
            value = app.ExamplesDropDown.Value;
            switch value
                case '1'
                    app.AuxiliarcodeTextArea.Value={''};
                    app.zmEditField.Value="zeros(1,41)";
                    app.xmEditField.Value="[0,0,0,0,0,0,0,0,0,0,0,-1.2500,-1.0000,-0.7500,-0.5000,-0.2500,0.2500,0.5000,0.7500,1.0000,1.2500,-1.2500,-1.0000,-0.7500,-0.5000,-0.2500,0.2500,0.5000,0.7500,1.0000,1.2500,-1.2500,-1.0000,-0.7500,-0.5000,-0.2500,0.2500,0.5000,0.7500,1.0000,1.2500]";
                    app.ymEditField.Value="[0,-1.2500,-1.0000,-0.7500,-0.5000,-0.2500,0.2500,0.5000,0.7500,1.0000,1.2500,0,0,0,0,0,0,0,0,0,0,-1.2500,-1.0000,-0.7500,-0.5000,-0.2500,0.2500,0.5000,0.7500,1.0000,1.2500,1.2500,1.0000,0.7500,0.5000,0.2500,-0.2500,-0.5000,-0.7500,-1.0000,-1.2500]";
                case '2'
                    app.AuxiliarcodeTextArea.Value={'N=16;','r = .2;','theta = linspace(0,2*pi,N+1);','theta(end) = [];'};
                    app.xmEditField.Value="r*cos(theta)";
                    app.ymEditField.Value="r*sin(theta)";
                    app.zmEditField.Value="zeros(1,N)";
                case '3'
                    app.AuxiliarcodeTextArea.Value={...
                        'N_arms = 4;', ...
                        'N_m = 4;', ...
                        'r_max = .04;', ...
                        'r_0 = .01;', ...
                        'v = 4*pi/16;    ', ...
                        ' ', ...
                        'l_max = (r_0*sqrt(1+cot(v)^2)/cot(v)) * (r_max/r_0-1);', ...
                        'ln = ( ((1:N_m)-1)/(N_m-1) )*l_max;', ...
                        'r = [];', ...
                        't = [];', ...
                        ['for m = 1:N_arms' ...
                        '    t_aux = log(1+cot(v)*ln/(r_0*sqrt(1+cot(v)^2)))/cot(v);' ...
                        '    r = [r, r_0*exp(cot(v)*t_aux)];' ...
                        '    t = [t, t_aux+(m-1)*2*pi/N_arms];' ...
                        'end'] ...
                        };
                    
                    app.xmEditField.Value="r.*cos(t)";
                    app.ymEditField.Value="r.*sin(t)";
                    app.zmEditField.Value="zeros(1,N_arms*N_m)";
            end
        end

        % Value changed function: DynamicrangedBSlider
        function DynamicrangedBSliderValueChanged(app, event)
            value = app.DynamicrangedBSlider.Value;
            app.DynamicrangedBEditField.Value = value;
            changeDynamicRange(app,app.UIAcousticImage, value, [])
        end

        % Value changed function: DynamicrangedBEditField
        function DynamicrangedBEditFieldValueChanged(app, event)
%             value = app.DynamicrangedBEditField.Value;
            app.DynamicrangedBSlider.Value= app.DynamicrangedBEditField.Value;
            DynamicrangedBSliderValueChanged(app);
        end

        % Button pushed function: EstimateRT60Button
        function EstimateRT60ButtonPushed(app, event)
            
            read_inputs(app);
            
            if app.refCoef==0
                t60 = 0;
            else
                receiverLoc = [app.xPos(1:2)' app.yPos(1:2)' app.zPos(1:2)'];
            
                % RT60 = 0.3;
                % refCoef =0;
                % RT60 = 0;
                sourceLoc = [app.xPosSource(1) app.yPosSource(1) app.zPosSource(1)];
            
                adj = app.room/2;adj(3) = 0;
                alpha = 1-app.refCoef.^2;
                Vol = prod(app.room);
                Sxy = app.room(1)*app.room(2);Syz = app.room(2)*app.room(3);Szx = app.room(1)*app.room(3);
                Amat = alpha*Sxy+alpha*Sxy+alpha*Syz+alpha*Syz+alpha*Szx+alpha*Szx;
                m=0.0082;
                app.RT60 = 1.1*55.3*Vol/(app.c*(Amat + 4*m*Vol));
                
%                 RIRs = genRIRs(app.fs, app.c, receiverLoc+adj, sourceLoc+adj, app.room, app.refCoef, app.RT60,[]);
%                 [~,t60,~] = EDC(RIRs{1,1}, app.fs, false);
                t60 = app.RT60;
            
            end

            app.RT60ValueLabel.Text = [num2str(round(t60*1000)) ' ms'];
        end

        % Value changing function: DynamicrangedBSlider
        function DynamicrangedBSliderValueChanging(app, event)
            changingValue = event.Value;
            app.DynamicrangedBEditField.Value = changingValue;
        end

        % Value changed function: BeamformerDropDown
        function BeamformerDropDownValueChanged(app, event)
            value = app.BeamformerDropDown.Value;
            app.FrequencyBandDropDown.Visible = strcmp(value,'CBF');
            app.FrequencyBandDropDownLabel.Visible = strcmp(value,'CBF');
            app.CenterFrequencyEditField.Visible = strcmp(value,'CBF');
            app.CenterFrequencyEditFieldLabel.Visible = strcmp(value,'CBF');
            
            app.FrequencyBinEditField.Visible = ~strcmp(value,'CBF');
            app.FrequencyBinEditFieldLabel.Visible = ~strcmp(value,'CBF');
            
            app.LoopGainEditField.Visible = strcmp(value,'CLEAN') || strcmp(value,'CLEANSC');
            app.LoopGainEditFieldLabel.Visible = strcmp(value,'CLEAN') || strcmp(value,'CLEANSC');
            app.MaxIterationsEditField.Visible = strcmp(value,'CLEAN') || strcmp(value,'CLEANSC');
            app.MaxIterationsEditFieldLabel.Visible = strcmp(value,'CLEAN') || strcmp(value,'CLEANSC');    
        end

        % Button pushed function: GenSignalsButton
        function GenSignalsButtonPushed(app, event)
            read_inputs(app);
            
       
            % Define ambient
            
%             receiverLoc = [app.xPos(:) app.yPos(:) app.zPos(:)];%#ok<NASGU>
%             sourceLoc = [app.xPosSource(:) app.yPosSource(:) app.zPosSource(:)];%#ok<NASGU>
% 
%            
% 
%             
% 
% 
%                 room=app.room; %#ok<NASGU,ADPROPLC> 
%                 refCoef = app.refCoef; %#ok<NASGU,ADPROPLC>
%                 c=app.c; %#ok<NASGU,ADPROPLC>
%                 f = app.f; %#ok<NASGU,ADPROPLC>
%                 fs=app.fs; %#ok<NASGU,ADPROPLC>
%                 nameAudio = app.nameAudio; %#ok<NASGU,ADPROPLC> 
%                 type = app.type; %#ok<NASGU,ADPROPLC>
%                 duration_sigs = app.duration;  %#ok<NASGU>
%                 SNR = app.SNR; %#ok<NASGU,ADPROPLC>
%                 d = uiprogressdlg(app.UIFigure,'Title','Please Wait',...
%                     'Message','Generating Signals');
%                 genSignalsISM;
%                 app.RT60ValueLabel.Text = [num2str(round(t60*1000)) ' ms'];    
%                 app.inputSignal = inputSignal;
%                 d.Value = 1; d.Message = 'Finished';
%                 pause(.1);
%                 close(d);
                rng('default');%% rng(3);
                
                adj = app.room/2;adj(3) = 0;
                
                PosSource = [app.xPosSource(:) app.yPosSource(:) app.zPosSource(:)]+adj;%#ok<NASGU>

                PosMic = [app.xPos(:) app.yPos(:) app.zPos(:)]+adj;%#ok<NASGU>
                dimRoom=app.room; %#ok<NASGU,ADPROPLC>
                SNR = app.SNR;
                beta = app.refCoef;
                Nsig = app.duration*app.fs;
                fs=app.fs;
                Fsbb=fs;
                
                EstimateRT60ButtonPushed(app);
                RT60 = app.RT60;
                
%                 %%type = {app.type,'audio'};
%                 %%par = {[fs, Nsig/fs, 1000],'./bib/adult_female_speech_continuous.wav'};
%                 type = {app.TypeDropDown.Value};
%                 switch app.TypeDropDown.Value
%                     case 'audio'
% %                         par = {'./bib/adult_female_speech_continuous.wav'};
%                         par = {['./bib/' app.WAVFilenameEditField.Value]};
%                     case 'wgn'
%                         par = {[fs, Nsig/fs]};
%                     case 'senoide'
%                         par = {[fs, Nsig/fs, app.f]};
%                 end
%                 
%                 switch app.TypeDropDown_2.Value
%                     case 'audio'
% %                         par = {'./bib/adult_female_speech_continuous.wav'};
%                         par = {par{:},['./bib/' app.WAVFilenameEditField_2.Value]};
%                         type = {type{:}, app.TypeDropDown_2.Value};
%                     case 'wgn'
%                         par = {par{:},[fs, Nsig/fs]};
%                         type = {type{:}, app.TypeDropDown_2.Value};
%                     case 'senoide'
%                         par = {par{:},[fs, Nsig/fs, app.f]};
%                         type = {type{:}, app.TypeDropDown_2.Value};
%                 end
                
                type={};
                par={};
                for i=1:app.nSources
                    tab = app.TabGroup2.Children(i).Children;
                    aux = tab.findobj('type','uidropdown').Value;
                    switch aux
                        case 'audio'
                            par = {par{:},[tab(1).Value]};
                        case 'wgn'
                            par = {par{:},[fs, Nsig/fs]};
                        case 'senoide'
                            par = {par{:},[fs, Nsig/fs, tab.findobj('type','uinumericeditfield').Value]};
                    end
                    type = {type{:}, aux};
                end
                
                [N,~] = size(PosSource);
                
                d = uiprogressdlg(app.UIFigure,'Title','Please Wait',...
                    'Message','Generating RIRs');
                
                [RIRs, rot] = genRIRs(app.fs, app.c, PosMic, PosSource, ...
                    dimRoom,beta, RT60, []);
                d.Value = .3; d.Message = 'Generating input signals';
                [signal,fso] = gensigsources(type,par,N,[]);
                
                [Sgns] = genSgns({RIRs, rot}, Fsbb, SNR, signal, fso,Nsig,[],[]);
                app.inputSignal = Sgns';
                d.Value = 1; d.Message = 'Finished';
                pause(.1);
                close(d);

%                 d = uiprogressdlg(app.UIFigure,'Title','Please Wait',...
%                     'Message','Generating Signals');
%                 genSignalsISM;
%                 app.RT60ValueLabel.Text = [num2str(round(t60*1000)) ' ms'];    
%                 app.inputSignal = inputSignal;
%                 d.Value = 1; d.Message = 'Finished';
%                 pause(.1);
%                 close(d);
        end

        % Menu selected function: DMenu
        function DMenuSelected(app, event)
            view(app.UIAcousticImage, [0 90]);
        end

        % Menu selected function: DMenu_2
        function DMenu_2Selected(app, event)
            view(app.UIAcousticImage, [30 30]);
        end

        % Button pushed function: ExportButton
        function ExportButtonPushed(app, event)
            if isfile(app.PathEditField.Value)
                selection = uiconfirm(app.UIFigure,'This file already exists.','Confirm export',...
                            'Icon','warning',...
                            'Options',{'Overwrite','Cancel'}, ...
                            'DefaultOption',1,'CancelOption',2);
                if strcmp(selection,'Cancel')
                    return;
                end
            end
            d = uiprogressdlg(app.UIFigure,'Title','Please Wait',...
                'Message',['Exporting ' app.TypeExportDropDown.Value]);
            if strcmp(app.TypeExportDropDown.Value,'Vector')
                exportgraphics(app.UIAcousticImage,['./images/' app.PathEditField.Value],'ContentType','vector');
            else
                exportgraphics(app.UIAcousticImage,['./images/' app.PathEditField.Value],'Resolution',app.DPIEditField.Value);
            end  
            d.Value = 1; d.Message = 'Finished';
            pause(.1);
            close(d);    
        end

        % Button pushed function: GenRunButton
        function GenRunButtonPushed(app, event)
            GenSignalsButtonPushed(app);
            RunBeamformerButtonPushed(app);
        end

        % Button pushed function: ShowArrayButton
        function ShowArrayButtonPushed(app, event)
            app.TabGroup.SelectedTab = app.ArrayTab;
            
            read_inputs(app);
            
            plot(app.UIArray,app.xPos,app.yPos,'*');
            legend(app.UIArray,'Microphones');
            title(app.UIArray,'Array Geometry');
            axis(app.UIArray,'image');
            xlabel(app.UIArray,'x (m)');
            ylabel(app.UIArray,'y (m)');
            
        end

        % Value changed function: TypeExportDropDown
        function TypeExportDropDownValueChanged(app, event)
            value = app.TypeExportDropDown.Value;

            if strcmp(value,'Image')
                app.DPIEditField.Visible = 'on';
                app.DPIEditFieldLabel.Visible = 'on';
            else
                app.DPIEditField.Visible = 'off';
                app.DPIEditFieldLabel.Visible = 'off';
            end
        end

        % Button pushed function: AddSourceButton
        function AddSourceButtonPushed(app, event)
            app.nSources = app.nSources+1;
            t2 = copyobj(app.TabGroup2.Children(1), app.TabGroup2);
            t2.Title = ['Source ' num2str(app.nSources)];
            app.TabGroup2.Children(app.nSources).Children.findobj('type','uidropdown').ValueChangedFcn=app.TabGroup2.Children(1).Children.findobj('type','uidropdown').ValueChangedFcn;
        end

        % Button pushed function: RemoveSourceButton
        function RemoveSourceButtonPushed(app, event)
            delete(app.TabGroup2.Children(end));
            app.nSources =  app.nSources-1;
        end
    end

    % Component initialization
    methods (Access = private)

        % Create UIFigure and components
        function createComponents(app)
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            app.UIFigure = uifigure('Visible', 'off');
            app.UIFigure.Name = 'MATLAB App';
            app.RunBeamformerButton = uibutton(app.UIFigure, 'push');
            app.RunBeamformerButton.ButtonPushedFcn = createCallbackFcn(app, @RunBeamformerButtonPushed, true);
            app.RunBeamformerButton.Text = 'Run Beamformer';
            app.ShowAmbientButton = uibutton(app.UIFigure, 'push');
            app.ShowAmbientButton.ButtonPushedFcn = createCallbackFcn(app, @ShowAmbientButtonPushed, true);
            app.ShowAmbientButton.Text = 'Show Ambient';
            app.xmEditFieldLabel = uilabel(app.UIFigure);
            app.xmEditFieldLabel.Text = 'x (m)';
            app.xmEditField = uieditfield(app.UIFigure, 'text');
            app.xmEditField.Value = '[-1,-1,-1,0,0,0,1,1,1]*.2';
            app.ArrayLabel = uilabel(app.UIFigure);
            app.ArrayLabel.Text = 'Array';
            app.ymEditFieldLabel = uilabel(app.UIFigure);
            app.ymEditFieldLabel.Text = 'y (m)';
            app.ymEditField = uieditfield(app.UIFigure, 'text');
            app.ymEditField.Value = '[-1,0,1,-1,0,1,-1,0,1]*.2';
            app.zmEditFieldLabel = uilabel(app.UIFigure);
            app.zmEditFieldLabel.Text = 'z (m)';
            app.zmEditField = uieditfield(app.UIFigure, 'text');
            app.zmEditField.Value = 'zeros(1,9)';
            app.TabGroup = uitabgroup(app.UIFigure);
            app.ArrayTab = uitab(app.TabGroup);
            app.ArrayTab.Title = 'Array';
            app.UIArray = uiaxes(app.ArrayTab);
            title(app.UIArray, 'Title')
            xlabel(app.UIArray, 'X')
            ylabel(app.UIArray, 'Y')
            app.AmbientTab = uitab(app.TabGroup);
            app.AmbientTab.Title = 'Ambient';
            app.UIScenario = uiaxes(app.AmbientTab);
            title(app.UIScenario, '')
            xlabel(app.UIScenario, 'X')
            ylabel(app.UIScenario, 'Y')
            app.AcousticImageTab = uitab(app.TabGroup);
            app.AcousticImageTab.Title = 'Acoustic Image';
            app.UIAcousticImage = uiaxes(app.AcousticImageTab);
            title(app.UIAcousticImage, 'Acoustic Image')
            xlabel(app.UIAcousticImage, 'X')
            ylabel(app.UIAcousticImage, 'Y')
            app.DynamicrangedBEditField = uieditfield(app.AcousticImageTab, 'numeric');
            app.DynamicrangedBEditField.ValueDisplayFormat = '%.2f';
            app.DynamicrangedBEditField.ValueChangedFcn = createCallbackFcn(app, @DynamicrangedBEditFieldValueChanged, true);
            app.DynamicrangedBEditField.Value = 9;
            app.ShowSourcesCheckBox = uicheckbox(app.AcousticImageTab);
            app.ShowSourcesCheckBox.ValueChangedFcn = createCallbackFcn(app, @ShowSourcesCheckBoxValueChanged, true);
            app.ShowSourcesCheckBox.Text = 'Show Sources';
            app.ShowArrayCheckBox = uicheckbox(app.AcousticImageTab);
            app.ShowArrayCheckBox.ValueChangedFcn = createCallbackFcn(app, @ShowArrayCheckBoxValueChanged, true);
            app.ShowArrayCheckBox.Text = 'Show Array';
            app.DynamicrangedBSliderLabel = uilabel(app.AcousticImageTab);
            app.DynamicrangedBSliderLabel.Text = 'Dynamic range (dB)';
            app.DynamicrangedBSlider = uislider(app.AcousticImageTab);
            app.DynamicrangedBSlider.Limits = [0 30];
            app.DynamicrangedBSlider.ValueChangedFcn = createCallbackFcn(app, @DynamicrangedBSliderValueChanged, true);
            app.DynamicrangedBSlider.ValueChangingFcn = createCallbackFcn(app, @DynamicrangedBSliderValueChanging, true);
            app.DynamicrangedBSlider.Value = 9;
            app.ShowLocalMaximaCheckBox = uicheckbox(app.AcousticImageTab);
            app.ShowLocalMaximaCheckBox.ValueChangedFcn = createCallbackFcn(app, @ShowLocalMaximaCheckBoxValueChanged, true);
            app.ShowLocalMaximaCheckBox.Text = 'Show Local Maxima';
            app.ExportButton = uibutton(app.AcousticImageTab, 'push');
            app.ExportButton.ButtonPushedFcn = createCallbackFcn(app, @ExportButtonPushed, true);
            app.ExportButton.Text = 'Export';
            app.PathEditFieldLabel = uilabel(app.AcousticImageTab);
            app.PathEditFieldLabel.Text = 'Path';
            app.PathEditField = uieditfield(app.AcousticImageTab, 'text');
            app.PathEditField.Value = 'test.png';
            app.TypeDropDown_2Label = uilabel(app.AcousticImageTab);
            app.TypeDropDown_2Label.Text = 'Type';
            app.TypeExportDropDown = uidropdown(app.AcousticImageTab);
            app.TypeExportDropDown.Items = {'Vector', 'Image'};
            app.TypeExportDropDown.ValueChangedFcn = createCallbackFcn(app, @TypeExportDropDownValueChanged, true);
            app.TypeExportDropDown.Value = 'Image';
            app.DPIEditFieldLabel = uilabel(app.AcousticImageTab);
            app.DPIEditFieldLabel.Text = 'DPI';
            app.DPIEditField = uieditfield(app.AcousticImageTab, 'numeric');
            app.DPIEditField.Value = 300;
            app.ScanningPlaneLabel = uilabel(app.UIFigure);
            app.ScanningPlaneLabel.Text = 'Scanning Plane';
            app.AcousticCanvasSimulatorLabel = uilabel(app.UIFigure);
            app.AcousticCanvasSimulatorLabel.Text = 'AcousticCanvas Simulator';
            app.DistancetoscanningplanemEditFieldLabel = uilabel(app.UIFigure);
            app.DistancetoscanningplanemEditFieldLabel.Text = 'Distance to scanning plane (m)';
            app.DistancetoscanningplanemEditField = uieditfield(app.UIFigure, 'numeric');
            app.DistancetoscanningplanemEditField.Value = 4;
            app.ScanningplanewidthmEditFieldLabel = uilabel(app.UIFigure);
            app.ScanningplanewidthmEditFieldLabel.Text = 'Scanning plane width (m)';
            app.ScanningplanewidthmEditField = uieditfield(app.UIFigure, 'numeric');
            app.ScanningplanewidthmEditField.Value = 10;
            app.ScanningplaneheightmEditFieldLabel = uilabel(app.UIFigure);
            app.ScanningplaneheightmEditFieldLabel.Text = 'Scanning plane height (m)';
            app.ScanningplaneheightmEditField = uieditfield(app.UIFigure, 'numeric');
            app.ScanningplaneheightmEditField.Value = 10;
            app.NumberofScanningpointswidthEditFieldLabel = uilabel(app.UIFigure);
            app.NumberofScanningpointswidthEditFieldLabel.Text = 'Number of  Scanning points (width)';
            app.NumberofScanningpointswidthEditField = uieditfield(app.UIFigure, 'numeric');
            app.NumberofScanningpointswidthEditField.Value = 100;
            app.NumberofScanningpointsheightEditFieldLabel = uilabel(app.UIFigure);
            app.NumberofScanningpointsheightEditFieldLabel.Text = 'Number of  Scanning points (height)';
            app.NumberofScanningpointsheightEditField = uieditfield(app.UIFigure, 'numeric');
            app.NumberofScanningpointsheightEditField.Value = 100;
            app.RoomConfigLabel = uilabel(app.UIFigure);
            app.RoomConfigLabel.Text = 'Room Config';
            app.ReflectionCoefficientEditFieldLabel = uilabel(app.UIFigure);
            app.ReflectionCoefficientEditFieldLabel.Text = 'Reflection Coefficient';
            app.ReflectionCoefficientEditField = uieditfield(app.UIFigure, 'numeric');
            app.ReflectionCoefficientEditField.Value = 0.5;
            app.RoomDimensionsmEditFieldLabel = uilabel(app.UIFigure);
            app.RoomDimensionsmEditFieldLabel.Text = 'Room Dimensions (m)';
            app.RoomDimensionsmEditField = uieditfield(app.UIFigure, 'text');
            app.RoomDimensionsmEditField.Value = '[15 15 6]';
            app.EstimatedRT60Label = uilabel(app.UIFigure);
            app.EstimatedRT60Label.Text = 'Estimated RT60:';
            app.RT60ValueLabel = uilabel(app.UIFigure);
            app.RT60ValueLabel.Text = 'RT60Value';
            app.BeamformerLabel = uilabel(app.UIFigure);
            app.BeamformerLabel.Text = 'Beamformer';
            app.FrequencyBandDropDownLabel = uilabel(app.UIFigure);
            app.FrequencyBandDropDownLabel.Text = 'Frequency Band';
            app.FrequencyBandDropDown = uidropdown(app.UIFigure);
            app.FrequencyBandDropDown.Items = {'All', 'Octave band'};
            app.FrequencyBandDropDown.ValueChangedFcn = createCallbackFcn(app, @FrequencyBandDropDownValueChanged, true);
            app.FrequencyBandDropDown.Value = 'Octave band';
            app.CenterFrequencyEditFieldLabel = uilabel(app.UIFigure);
            app.CenterFrequencyEditFieldLabel.Text = 'Center Frequency';
            app.CenterFrequencyEditField = uieditfield(app.UIFigure, 'numeric');
            app.CenterFrequencyEditField.Value = 600;
            app.ExamplesDropDownLabel = uilabel(app.UIFigure);
            app.ExamplesDropDownLabel.Text = 'Examples';
            app.ExamplesDropDown = uidropdown(app.UIFigure);
            app.ExamplesDropDown.Items = {'Star 2.5x2.5 m', 'Circular', 'Multi-spiral', ''};
            app.ExamplesDropDown.ItemsData = {'1', '2', '3', '4', '5'};
            app.ExamplesDropDown.ValueChangedFcn = createCallbackFcn(app, @ExamplesDropDownValueChanged, true);
            app.ExamplesDropDown.Value = '1';
            app.EstimateRT60Button = uibutton(app.UIFigure, 'push');
            app.EstimateRT60Button.ButtonPushedFcn = createCallbackFcn(app, @EstimateRT60ButtonPushed, true);
            app.EstimateRT60Button.Text = 'Estimate RT60';
            app.BeamformerLabel_2 = uilabel(app.UIFigure);
            app.BeamformerLabel_2.Text = 'Beamformer:';
            app.BeamformerDropDown = uidropdown(app.UIFigure);
            app.BeamformerDropDown.Items = {'Conventional Beamformer', 'DAS (single frequency)', 'Minimum Variance', 'Functional Beamforming', 'CLEAN', 'CLEAN-SC'};
            app.BeamformerDropDown.ItemsData = {'CBF', 'DAS', 'MV', 'FB', 'CLEAN', 'CLEANSC'};
            app.BeamformerDropDown.ValueChangedFcn = createCallbackFcn(app, @BeamformerDropDownValueChanged, true);
            app.BeamformerDropDown.Value = 'DAS';
            app.FrequencyBinEditFieldLabel = uilabel(app.UIFigure);
            app.FrequencyBinEditFieldLabel.Text = 'Frequency Bin';
            app.FrequencyBinEditField = uieditfield(app.UIFigure, 'numeric');
            app.FrequencyBinEditField.Value = 600;
            app.LoopGainEditFieldLabel = uilabel(app.UIFigure);
            app.LoopGainEditFieldLabel.Text = 'Loop Gain';
            app.LoopGainEditField = uieditfield(app.UIFigure, 'numeric');
            app.LoopGainEditField.Value = 0.9;
            app.MaxIterationsEditFieldLabel = uilabel(app.UIFigure);
            app.MaxIterationsEditFieldLabel.Text = 'Max Iterations';
            app.MaxIterationsEditField = uieditfield(app.UIFigure, 'numeric');
            app.MaxIterationsEditField.Value = 50;
            app.GenSignalsButton = uibutton(app.UIFigure, 'push');
            app.GenSignalsButton.ButtonPushedFcn = createCallbackFcn(app, @GenSignalsButtonPushed, true);
            app.GenSignalsButton.Text = 'Gen Signals';
            app.TabGroup2 = uitabgroup(app.UIFigure);
            app.Source1Tab = uitab(app.TabGroup2);
            app.Source1Tab.Title = 'Source 1';
            app.SourceLocationLabel = uilabel(app.Source1Tab);
            app.SourceLocationLabel.Text = 'Source Location';
            app.SignalfrequencyHzEditFieldLabel = uilabel(app.Source1Tab);
            app.SignalfrequencyHzEditFieldLabel.Text = 'Signal frequency (Hz)';
            app.SignalfrequencyHzEditField = uieditfield(app.Source1Tab, 'numeric');
            app.SignalfrequencyHzEditField.Value = 600;
            app.xmEditField_2Label = uilabel(app.Source1Tab);
            app.xmEditField_2Label.Text = 'x (m)';
            app.xSourceEditField = uieditfield(app.Source1Tab, 'text');
            app.xSourceEditField.Value = '-3';
            app.ymEditField_2Label = uilabel(app.Source1Tab);
            app.ymEditField_2Label.Text = 'y (m)';
            app.ySourceEditField = uieditfield(app.Source1Tab, 'text');
            app.ySourceEditField.Value = '-1';
            app.zmEditField_2Label = uilabel(app.Source1Tab);
            app.zmEditField_2Label.Text = 'z (m)';
            app.zSourceEditField = uieditfield(app.Source1Tab, 'text');
            app.zSourceEditField.Value = '4';
            app.TypeDropDownLabel = uilabel(app.Source1Tab);
            app.TypeDropDownLabel.Text = 'Type';
            app.TypeDropDown = uidropdown(app.Source1Tab);
            app.TypeDropDown.Items = {'Speech', 'Sinusoidal', 'WGN'};
            app.TypeDropDown.ItemsData = {'audio', 'senoide', 'wgn'};
            app.TypeDropDown.ValueChangedFcn = createCallbackFcn(app, @TypeDropDownValueChanged, true);
            app.TypeDropDown.Value = 'audio';
            app.WAVFilenameEditFieldLabel = uilabel(app.Source1Tab);
            app.WAVFilenameEditFieldLabel.Tag = 'WAVFilenameLabel';
            app.WAVFilenameEditFieldLabel.Text = 'WAV Filename';
            app.WAVFilenameEditField = uieditfield(app.Source1Tab, 'text');
            app.WAVFilenameEditField.Value = 'audios/adult_female_speech_continuous.wav';
            app.GenRunButton = uibutton(app.UIFigure, 'push');
            app.GenRunButton.ButtonPushedFcn = createCallbackFcn(app, @GenRunButtonPushed, true);
            app.GenRunButton.Text = 'Gen & Run';
            app.AuxiliarcodeLabel = uilabel(app.UIFigure);
            app.AuxiliarcodeLabel.Text = 'Auxiliar code:';
            app.AuxiliarcodeTextArea = uitextarea(app.UIFigure);
            app.ShowArrayButton = uibutton(app.UIFigure, 'push');
            app.ShowArrayButton.ButtonPushedFcn = createCallbackFcn(app, @ShowArrayButtonPushed, true);
            app.ShowArrayButton.Text = 'Show Array';
            app.AddSourceButton = uibutton(app.UIFigure, 'push');
            app.AddSourceButton.ButtonPushedFcn = createCallbackFcn(app, @AddSourceButtonPushed, true);
            app.AddSourceButton.Text = 'Add Source';
            app.RemoveSourceButton = uibutton(app.UIFigure, 'push');
            app.RemoveSourceButton.ButtonPushedFcn = createCallbackFcn(app, @RemoveSourceButtonPushed, true);
            app.RemoveSourceButton.Text = 'Remove Source';
            app.SourcesLabel = uilabel(app.UIFigure);
            app.SourcesLabel.Text = 'Sources';
            app.ContextMenu = uicontextmenu(app.UIFigure);
            % Assign app.ContextMenu
            app.UIAcousticImage.ContextMenu = app.ContextMenu;
            app.DMenu = uimenu(app.ContextMenu);
            app.DMenu.MenuSelectedFcn = createCallbackFcn(app, @DMenuSelected, true);
            app.DMenu.Text = '2D';
            app.DMenu_2 = uimenu(app.ContextMenu);
            app.DMenu_2.MenuSelectedFcn = createCallbackFcn(app, @DMenu_2Selected, true);
            app.DMenu_2.Text = '3D';
            app.InputSignalLabel = uilabel(app.UIFigure);
            app.InputSignalLabel.Text = 'Input Signal';
            app.FrequencysampleHzEditFieldLabel = uilabel(app.UIFigure);
            app.FrequencysampleHzEditFieldLabel.Text = 'Frequency sample (Hz)';
            app.FrequencysampleHzEditField = uieditfield(app.UIFigure, 'numeric');
            app.FrequencysampleHzEditField.ValueDisplayFormat = '%.0f';
            app.FrequencysampleHzEditField.Value = 24000;
            app.DurationsEditFieldLabel = uilabel(app.UIFigure);
            app.DurationsEditFieldLabel.Text = 'Duration (s)';
            app.DurationsEditField = uieditfield(app.UIFigure, 'numeric');
            app.DurationsEditField.ValueDisplayFormat = '%.2f';
            app.DurationsEditField.Value = 1;
            app.SNRdBEditFieldLabel = uilabel(app.UIFigure);
            app.SNRdBEditFieldLabel.Text = 'SNR (dB)';
            app.SNRdBEditField = uieditfield(app.UIFigure, 'numeric');
            app.SNRdBEditField.ValueDisplayFormat = '%.2f';
            app.SNRdBEditField.Value = Inf;
            app.WindowLengthmsEditFieldLabel = uilabel(app.UIFigure);
            app.WindowLengthmsEditFieldLabel.Text = 'Window Length (ms)';
            app.WindowLengthmsEditField = uieditfield(app.UIFigure, 'numeric');
            app.WindowLengthmsEditField.Value = 32;
            % Show the figure after all components are created
            app.UIFigure.Visible = 'on';
        end
    end

    % App creation and deletion
    methods (Access = public)

        % Construct app
        function app = AcousticCanvas

            % Create UIFigure and components
            createComponents(app)

            % Register the app with App Designer
            registerApp(app, app.UIFigure)

            % Execute the startup function
            runStartupFcn(app, @startupFcn)

            if nargout == 0
                clear app
            end
        end

        % Code that executes before app deletion
        function delete(app)

            % Delete UIFigure when app is deleted
            delete(app.UIFigure)
        end
    end
end